classdef ANTSD_FourierTransform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TheFastandFouriersLabel     matlab.ui.control.Label
        SamplingFreqEditField       matlab.ui.control.NumericEditField
        SamplingFreqEditFieldLabel  matlab.ui.control.Label
        ThreeDButton                matlab.ui.control.Button
        TimerangeEditField_Signal   matlab.ui.control.EditField
        TimerangeEditFieldLabel     matlab.ui.control.Label
        TimerangeEditField_Kernel   matlab.ui.control.EditField
        TimerangeEditField_2Label   matlab.ui.control.Label
        TimexImagButton             matlab.ui.control.Button
        RealxImagButton             matlab.ui.control.Button
        TimexRealButton             matlab.ui.control.Button
        SignalPhaseSlider           matlab.ui.control.Slider
        SignalPhasedegSliderLabel   matlab.ui.control.Label
        EditField_SignalPhase       matlab.ui.control.NumericEditField
        SignalAmplitudeSlider       matlab.ui.control.Slider
        SignalAmplitudeSliderLabel  matlab.ui.control.Label
        SignalFrequencySlider       matlab.ui.control.Slider
        SignalFrequencySliderLabel  matlab.ui.control.Label
        EditField_SignalAmp         matlab.ui.control.NumericEditField
        EditField_SignalFreq        matlab.ui.control.NumericEditField
        EditField_KernCycl          matlab.ui.control.NumericEditField
        EditField_KernFreq          matlab.ui.control.NumericEditField
        Label_DemixedSignalusingFourierTransformwithchosenKernel  matlab.ui.control.Label
        KernelCyclesSlider          matlab.ui.control.Slider
        KernelCyclesLabel           matlab.ui.control.Label
        KernelFrequencySlider       matlab.ui.control.Slider
        KernelFrequencySliderLabel  matlab.ui.control.Label
        UIAxes_DemPhase             matlab.ui.control.UIAxes
        UIAxes_DemFreqPower         matlab.ui.control.UIAxes
        UIAxes_FourierTransform     matlab.ui.control.UIAxes
        UIAxes_Kernel               matlab.ui.control.UIAxes
        UIAxes_Signal               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        kernpersp = [-37.5 30]; % Perspective of the Kernel plot
        Signal_time = linspace(0,1,1000);
        Signal_const = .5;
    end


    methods (Access = private)
        
        function startupFcn(app)
     
            % Initialize
            plot_Signal(app)

        end
        
        function plot_Signal(app)
            x = app.Signal_time;
            const = app.Signal_const;

            f = app.EditField_SignalFreq.Value;
            A = app.EditField_SignalAmp.Value;
            ph = deg2rad(app.EditField_SignalPhase.Value);

            wave = A * sin(2 * pi * x * f + ph) + const;
            plot(app.UIAxes_Signal, wave)
        end   
        
        function wavelet = make_wavlet(app)
            time = -2:1/10000:2;
            f = app.EditField_KernFreq.Value; % Frequency
            cyc = app.EditField_KernCycl.Value; % Num of cycles

            s = cyc/(2*pi*f); % Gaussian spread
            sinewave = exp(2*pi*1i*f.*time);
            gauswindow = exp(-time.^2./(2*s^2));
            wavelet = sinewave .* gauswindow;
        end

        function plot_Kernel(app)
            time = -2:1/10000:2;
            kern = make_wavlet(app);
            plot3(app.UIAxes_Kernel,time,real(kern),imag(kern))
            view(app.UIAxes_Kernel, app.kernpersp)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: SignalFrequencySlider
        function SignalFrequencySliderValueChanging(app, event)
            value = round(event.Value);

            % Update
            app.EditField_SignalFreq.Value = value;
            plot_Signal(app)
        end

        % Value changed function: EditField_SignalFreq
        function EditField_SignalFreqValueChanged(app, event)
            value = event.Value;
            
            % Constrain value
            if value < 0
                value = 0;
                app.EditField_SignalFreq.Value = value;
            elseif value > 100
                value = 100;
                app.EditField_SignalFreq.Value = value;
            end

            % Update
            app.SignalFrequencySlider.Value = value;
        end

        % Value changing function: SignalAmplitudeSlider
        function SignalAmplitudeSliderValueChanging(app, event)
            value = event.Value;

            % Update
            app.EditField_SignalAmp.Value = value;
            plot_Signal(app)
        end

        % Value changed function: EditField_SignalAmp
        function EditField_SignalAmpValueChanged(app, event)
            value = event.Value;
            
            % Constrain value
            if value < 0
                value = 0;
                app.EditField_SignalAmp.Value = value;
            elseif value > 10
                value = 10;
                app.EditField_SignalAmp.Value = value;
            end

            % Update
            app.SignalAmplitudeSlider.Value = value;       
        end

        % Value changing function: SignalPhaseSlider
        function SignalPhaseSliderValueChanging(app, event)
            value = event.Value;

            % Update
            app.EditField_SignalPhase.Value = value;
            plot_Signal(app)
        end

        % Value changed function: EditField_SignalPhase
        function EditField_SignalPhaseValueChanged(app, event)
            value = event.Value;
            
            % Constrain value
            if value < -360
                value = -360;
                app.EditField_SignalPhase.Value = value;
            elseif value > 360
                value = 360;
                app.EditField_SignalPhase.Value = value;
            end

            % Update
            app.SignalPhaseSlider.Value = value; 
        end

        % Value changing function: KernelFrequencySlider
        function KernelFrequencySliderValueChanging(app, event)
            value = round(event.Value);
            
            % Update
            app.EditField_KernFreq.Value = value;
            plot_Kernel(app)
        end

        % Value changed function: EditField_KernFreq
        function EditField_KernFreqValueChanged(app, event)
            value = event.Value;
            
            % Constrain value
            if value < 0
                value = 0;
                app.EditField_KernFreq.Value = value;
            elseif value > 100
                value = 100;
                app.EditField_KernFreq.Value = value;
            end

            % Update
            app.KernelFrequencySlider.Value = value;
        end

        % Value changing function: KernelCyclesSlider
        function KernelCyclesSliderValueChanging(app, event)
            value = round(event.Value);
            
            % Update
            app.EditField_KernCycl.Value = value;     
            plot_Kernel(app)
        end

        % Value changed function: EditField_KernCycl
        function EditField_KernCyclValueChanged(app, event)
            value = event.Value;
            
            % Constrain value
            if value < 1
                value = 1;
                app.EditField_KernCycl.Value = value;
            elseif value > 21
                value = 21;
                app.EditField_KernCycl.Value = value;
            end

            % Update
            app.KernelCyclesSlider.Value = value;         
        end

        % Button pushed function: TimexImagButton
        function TimexImagButtonPushed(app, event)
            app.kernpersp = [0 0];
            view(app.UIAxes_Kernel, app.kernpersp)
        end

        % Button pushed function: RealxImagButton
        function RealxImagButtonPushed(app, event)
            app.kernpersp = [90 0];
            view(app.UIAxes_Kernel, app.kernpersp)
        end

        % Button pushed function: TimexRealButton
        function TimexRealButtonPushed(app, event)
            app.kernpersp = [0 90];            
            view(app.UIAxes_Kernel, app.kernpersp)
        end

        % Button pushed function: ThreeDButton
        function ThreeDButtonPushed(app, event)
            app.kernpersp = [-37.5 30];            
            view(app.UIAxes_Kernel, app.kernpersp)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Signal = uiaxes(app.UIFigure);
            title(app.UIAxes_Signal, 'Signal')
            xlabel(app.UIAxes_Signal, 'Time (s)')
            ylabel(app.UIAxes_Signal, 'Amplitude')
            zlabel(app.UIAxes_Signal, 'Z')
            app.UIAxes_Signal.XLim = [0 100];
            app.UIAxes_Signal.YLim = [-10 10];
            app.UIAxes_Kernel = uiaxes(app.UIFigure);
            title(app.UIAxes_Kernel, 'Kernel')
            xlabel(app.UIAxes_Kernel, 'Time (s)')
            ylabel(app.UIAxes_Kernel, 'Real')
            zlabel(app.UIAxes_Kernel, 'Imaginary')
            app.UIAxes_Kernel.XLim = [-2 2];
            app.UIAxes_Kernel.YLim = [-2 2];
            app.UIAxes_FourierTransform = uiaxes(app.UIFigure);
            title(app.UIAxes_FourierTransform, 'Fourier Transform')
            xlabel(app.UIAxes_FourierTransform, 'Real numbers')
            ylabel(app.UIAxes_FourierTransform, 'Imaginary numbers')
            zlabel(app.UIAxes_FourierTransform, 'Z')
            app.UIAxes_DemFreqPower = uiaxes(app.UIFigure);
            title(app.UIAxes_DemFreqPower, 'Demixed Frequency & Power')
            xlabel(app.UIAxes_DemFreqPower, 'Frequency')
            ylabel(app.UIAxes_DemFreqPower, 'Power')
            zlabel(app.UIAxes_DemFreqPower, 'Z')
            app.UIAxes_DemPhase = uiaxes(app.UIFigure);
            title(app.UIAxes_DemPhase, 'Demixed Phase')
            xlabel(app.UIAxes_DemPhase, 'Frequency')
            ylabel(app.UIAxes_DemPhase, 'Phase')
            zlabel(app.UIAxes_DemPhase, 'Z')
            app.KernelFrequencySliderLabel = uilabel(app.UIFigure);
            app.KernelFrequencySliderLabel.Text = {'Kernel'; 'Frequency'};
            app.KernelFrequencySlider = uislider(app.UIFigure);
            app.KernelFrequencySlider.ValueChangingFcn = createCallbackFcn(app, @KernelFrequencySliderValueChanging, true);
            app.KernelCyclesLabel = uilabel(app.UIFigure);
            app.KernelCyclesLabel.Text = {'Kernel'; 'Cycles'};
            app.KernelCyclesSlider = uislider(app.UIFigure);
            app.KernelCyclesSlider.Limits = [1 21];
            app.KernelCyclesSlider.MajorTicks = [1 5 9 13 17 21];
            app.KernelCyclesSlider.MajorTickLabels = {'1', '5', '9', '13', '17', '21'};
            app.KernelCyclesSlider.ValueChangingFcn = createCallbackFcn(app, @KernelCyclesSliderValueChanging, true);
            app.KernelCyclesSlider.MinorTicks = [];
            app.KernelCyclesSlider.Value = 1;
            app.Label_DemixedSignalusingFourierTransformwithchosenKernel = uilabel(app.UIFigure);
            app.Label_DemixedSignalusingFourierTransformwithchosenKernel.Text = 'Demixed Signal using Fourier Transform with chosen Kernel';
            app.EditField_KernFreq = uieditfield(app.UIFigure, 'numeric');
            app.EditField_KernFreq.ValueChangedFcn = createCallbackFcn(app, @EditField_KernFreqValueChanged, true);
            app.EditField_KernCycl = uieditfield(app.UIFigure, 'numeric');
            app.EditField_KernCycl.ValueChangedFcn = createCallbackFcn(app, @EditField_KernCyclValueChanged, true);
            app.EditField_KernCycl.Value = 1;
            app.EditField_SignalFreq = uieditfield(app.UIFigure, 'numeric');
            app.EditField_SignalFreq.ValueChangedFcn = createCallbackFcn(app, @EditField_SignalFreqValueChanged, true);
            app.EditField_SignalFreq.Value = 1;
            app.EditField_SignalAmp = uieditfield(app.UIFigure, 'numeric');
            app.EditField_SignalAmp.ValueChangedFcn = createCallbackFcn(app, @EditField_SignalAmpValueChanged, true);
            app.EditField_SignalAmp.Value = 1;
            app.SignalFrequencySliderLabel = uilabel(app.UIFigure);
            app.SignalFrequencySliderLabel.Text = {'Signal'; 'Frequency'};
            app.SignalFrequencySlider = uislider(app.UIFigure);
            app.SignalFrequencySlider.ValueChangingFcn = createCallbackFcn(app, @SignalFrequencySliderValueChanging, true);
            app.SignalFrequencySlider.Value = 1;
            app.SignalAmplitudeSliderLabel = uilabel(app.UIFigure);
            app.SignalAmplitudeSliderLabel.Text = {'Signal'; 'Amplitude'};
            app.SignalAmplitudeSlider = uislider(app.UIFigure);
            app.SignalAmplitudeSlider.Limits = [0 10];
            app.SignalAmplitudeSlider.MajorTicks = [0 2 4 6 8 10];
            app.SignalAmplitudeSlider.MajorTickLabels = {'0', '2', '4', '6', '8', '10'};
            app.SignalAmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @SignalAmplitudeSliderValueChanging, true);
            app.SignalAmplitudeSlider.MinorTicks = [1 3 5 7 9];
            app.SignalAmplitudeSlider.Value = 1;
            app.EditField_SignalPhase = uieditfield(app.UIFigure, 'numeric');
            app.EditField_SignalPhase.ValueChangedFcn = createCallbackFcn(app, @EditField_SignalPhaseValueChanged, true);
            app.SignalPhasedegSliderLabel = uilabel(app.UIFigure);
            app.SignalPhasedegSliderLabel.Text = {'Signal'; 'Phase (deg)'};
            app.SignalPhaseSlider = uislider(app.UIFigure);
            app.SignalPhaseSlider.Limits = [-360 360];
            app.SignalPhaseSlider.MajorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.SignalPhaseSlider.MajorTickLabels = {'-360', '', '-180', '', '0', '', '180', '', '360'};
            app.SignalPhaseSlider.ValueChangingFcn = createCallbackFcn(app, @SignalPhaseSliderValueChanging, true);
            app.SignalPhaseSlider.MinorTicks = [];
            app.TimexRealButton = uibutton(app.UIFigure, 'push');
            app.TimexRealButton.ButtonPushedFcn = createCallbackFcn(app, @TimexRealButtonPushed, true);
            app.TimexRealButton.Text = 'Time x Real';
            app.RealxImagButton = uibutton(app.UIFigure, 'push');
            app.RealxImagButton.ButtonPushedFcn = createCallbackFcn(app, @RealxImagButtonPushed, true);
            app.RealxImagButton.Text = 'Real x Imag';
            app.TimexImagButton = uibutton(app.UIFigure, 'push');
            app.TimexImagButton.ButtonPushedFcn = createCallbackFcn(app, @TimexImagButtonPushed, true);
            app.TimexImagButton.Text = 'Time x Imag';
            app.TimerangeEditField_2Label = uilabel(app.UIFigure);
            app.TimerangeEditField_2Label.Text = 'Time range';
            app.TimerangeEditField_Kernel = uieditfield(app.UIFigure, 'text');
            app.TimerangeEditField_Kernel.Placeholder = '0,10';
            app.TimerangeEditFieldLabel = uilabel(app.UIFigure);
            app.TimerangeEditFieldLabel.Text = 'Time range';
            app.TimerangeEditField_Signal = uieditfield(app.UIFigure, 'text');
            app.TimerangeEditField_Signal.Placeholder = '0,10';
            app.ThreeDButton = uibutton(app.UIFigure, 'push');
            app.ThreeDButton.ButtonPushedFcn = createCallbackFcn(app, @ThreeDButtonPushed, true);
            app.ThreeDButton.Text = '3D';
            app.SamplingFreqEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFreqEditFieldLabel.Text = 'Sampling Freq';
            app.SamplingFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFreqEditField.RoundFractionalValues = 'on';
            app.SamplingFreqEditField.Value = 500;
            app.TheFastandFouriersLabel = uilabel(app.UIFigure);
            app.TheFastandFouriersLabel.Text = 'The Fast and Fouriers';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ANTSD_FourierTransform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end